classdef UBC_BMS_Monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SystemPanel              matlab.ui.container.Panel
        GridLayout3              matlab.ui.container.GridLayout
        UpdateButton             matlab.ui.control.Button
        ChargeModeDropDown       matlab.ui.control.DropDown
        ChargeModeDropDownLabel  matlab.ui.control.Label
        SystemStateSwitch        matlab.ui.control.Switch
        SystemStateSwitchLabel   matlab.ui.control.Label
        KdEditField              matlab.ui.control.NumericEditField
        KdEditFieldLabel         matlab.ui.control.Label
        KiEditField              matlab.ui.control.NumericEditField
        KiEditFieldLabel         matlab.ui.control.Label
        KpEditField              matlab.ui.control.NumericEditField
        KpEditFieldLabel         matlab.ui.control.Label
        DataPanel                matlab.ui.container.Panel
        GridLayout2              matlab.ui.container.GridLayout
        TabGroup                 matlab.ui.container.TabGroup
        SolarPanelTab            matlab.ui.container.Tab
        GridLayout6              matlab.ui.container.GridLayout
        InputPower_Axis          matlab.ui.control.UIAxes
        InputCurrent_Axis        matlab.ui.control.UIAxes
        InputVoltage_Axes        matlab.ui.control.UIAxes
        ChargingSlotTab          matlab.ui.container.Tab
        GridLayout6_2            matlab.ui.container.GridLayout
        SlotPower_Axis           matlab.ui.control.UIAxes
        SlotCurrent_Axis         matlab.ui.control.UIAxes
        SlotVoltage_Axis         matlab.ui.control.UIAxes
        BatteryTab               matlab.ui.container.Tab
        GridLayout4              matlab.ui.container.GridLayout
        BatteryLevel_Axis        matlab.ui.control.UIAxes
        COMMPanel                matlab.ui.container.Panel
        GridLayout               matlab.ui.container.GridLayout
        COMMStateLamp            matlab.ui.control.Lamp
        COMMStateLampLabel       matlab.ui.control.Label
        COMMSwitch               matlab.ui.control.Switch
        COMMChannelDropDown      matlab.ui.control.DropDown
        COMMChannelLabel         matlab.ui.control.Label
    end


    properties (Access = private)
        % debug
        flag_run
        % COMM
        SerialObject                                                        % Serial Port Object
        % Transmission Info
        Transmission_Size
        COMM_Type_L
        COMM_Type_H
        COMM_Type_Change_Flag
        % Feedback
        FB_Size
        FB
        % Command
        CMD_Size
        CMD
        % Data
        Data_Package_Length
        Data_Display_Length
        Battery_Level
        Slot_Voltage
        % Display
        Color_Data
        Color_Index
        % System
        SysState
        ChargeMode
    end

    methods (Access = private)
        %% Serial Port Communication
        function serialconfig(app)
            % Initialize COMM
            app.SerialObject = serialport(app.COMMChannelDropDown.Value,312500,"Parity",'none',"DataBits",8,"StopBits",1);
            configureCallback(app.SerialObject,"byte",1,@app.COMM_info);    % Receive transmission size
        end

        function COMM_info(app,~,~)
            app.COMM_Type_L = read(app.SerialObject,1,"uint8");
            assignin("base","app_COMM_Type_L",app.COMM_Type_L);
            % Determine transmission size and then receive data
            if app.COMM_Type_L == 0x01
                %% Hello
                write(app.SerialObject,0x01,"uint8");

                app.SysState = read(app.SerialObject,1,"uint8");
                if app.SysState == 0
                    app.SystemStateSwitch.Value = "Off";
                else
                    app.SystemStateSwitch.Value = "On";
                end

                app.ChargeMode = read(app.SerialObject,1,"uint8");
                if app.ChargeMode == 0
                    app.ChargeModeDropDown.Value = "Fast";
                else
                    app.ChargeModeDropDown.Value = "Balance";
                end

                app.KpEditField.Value = read(app.SerialObject,1,"single");
                app.KiEditField.Value = read(app.SerialObject,1,"single");
                app.KdEditField.Value = read(app.SerialObject,1,"single");

                write(app.SerialObject,0x02,"uint8");                       % Ask for feedback
            elseif app.COMM_Type_L == 0x02
                %% Feedback
                write(app.SerialObject,0x02,"uint8");                       % Ask for feedback

                app.FB = read(app.SerialObject,app.FB_Size,"single");
                assignin("base","app_Data_FB_Float",app.FB);

                Display_Data(app);

                Update_COMM_Type(app);
            elseif app.COMM_Type_L == 0x03
                %% CMD
                for i = 1:3
                    write(app.SerialObject,app.CMD(1,i),"single");
                end

                Update_COMM_Type(app);
            elseif app.COMM_Type_L == 0x04
                %% System State
                write(app.SerialObject,app.SysState,"uint8");

                Update_COMM_Type(app);
            elseif app.COMM_Type_L == 0x05
                %% Charging Mode
                write(app.SerialObject,app.ChargeMode,"uint8");

                Update_COMM_Type(app);
            end
        end

        function Update_COMM_Type(app,~,~)
            if app.COMM_Type_Change_Flag == 1                               % COMM Type changed?
                app.COMM_Type_Change_Flag = 0;                              % Reset flag
                write(app.SerialObject,app.COMM_Type_H,"uint8");            % Transmit new COMM Type
            else
                write(app.SerialObject,0x02,"uint8");                       % Ask for feedback
            end
        end

        function Display_Data (app,~,~)
            % app.Battery_Level.B1 = [app.Battery_Level.B1(app.Data_Package_Length +1:end) app.FB(0*app.Data_Package_Length+1 : 4*app.Data_Package_Length)];
            % app.Battery_Level.B2 = [app.Battery_Level.B2(app.Data_Package_Length+1:end) app.FB(1*app.Data_Package_Length+1 : 5*app.Data_Package_Length)];
            % app.Battery_Level.B3 = [app.Battery_Level.B3(app.Data_Package_Length+1:end) app.FB(2*app.Data_Package_Length+1 : 6*app.Data_Package_Length)];

            app.Slot_Voltage.S1 = [app.Slot_Voltage.S1(app.Data_Package_Length +1:end) app.FB(0*app.Data_Package_Length+1 : 1*app.Data_Package_Length)];
            app.Slot_Voltage.S2 = [app.Slot_Voltage.S2(app.Data_Package_Length+1:end) app.FB(1*app.Data_Package_Length+1 : 2*app.Data_Package_Length)];
            app.Slot_Voltage.S3 = [app.Slot_Voltage.S3(app.Data_Package_Length+1:end) app.FB(2*app.Data_Package_Length+1 : 3*app.Data_Package_Length)];

            % Prepare the plot

            % bl_1 = plot(app.BatteryLevel_Axis,-9.9995:0.0005:0, app.Battery_Level.B1, "Color", app.Color_Data(1,:));
            % hold(app.BatteryLevel_Axis, 'on');
            % bl_2 = plot(app.BatteryLevel_Axis,-9.9995:0.0005:0, app.Battery_Level.B2, "Color", app.Color_Data(2,:));
            % bl_3 = plot(app.BatteryLevel_Axis,-9.9995:0.0005:0, app.Battery_Level.B3, "Color", app.Color_Data(3,:));
            % hold(app.BatteryLevel_Axis, 'off');


            sv_1 = plot(app.SlotVoltage_Axis, app.Slot_Voltage.S1, "Color", app.Color_Data(1,:));
            hold(app.SlotVoltage_Axis, 'on');
            sv_2 = plot(app.SlotVoltage_Axis, app.Slot_Voltage.S2, "Color", app.Color_Data(2,:));
            sv_3 = plot(app.SlotVoltage_Axis, app.Slot_Voltage.S3, "Color", app.Color_Data(3,:));
            hold(app.SlotVoltage_Axis, 'off');

            % Graph Setting
            % legend(app.BatteryLevel_Axis, [bl_1, bl_2, bl_3], {'Battery 1', 'Battery 2', 'Battery 3'});
            legend(app.SlotVoltage_Axis, [sv_1, sv_2, sv_3], {'Slot 1', 'Slot 2', 'Slot 3'});
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;

            app.flag_run = 0;
            assignin("base","app_flag_run",app.flag_run);
            % COMM Setting
            app.COMMChannelDropDown.Items = serialportlist("all");

            % Transmission Initialization
            app.COMM_Type_Change_Flag = 0;
            app.COMM_Type_L = 0x00;
            app.COMM_Type_H = 0x00;
            assignin("base","app_COMM_Type_L",app.COMM_Type_L);
            assignin("base","app_COMM_Type_H",app.COMM_Type_H);

            % Lamp Initialization
            app.COMMStateLamp.Color = [1 0 0];

            % Feedback Initialization
            app.Data_Package_Length = 1000;
            app.FB_Size = app.Data_Package_Length*3;

            app.FB = zeros(1,app.FB_Size,"single");

            app.Data_Display_Length = 20;

            % app.Battery_Level.B1 = zeros(1,app.Data_Package_Length*app.Data_Display_Length,'single');
            % app.Battery_Level.B2 = zeros(1,app.Data_Package_Length*app.Data_Display_Length,'single');
            % app.Battery_Level.B3 = zeros(1,app.Data_Package_Length*app.Data_Display_Length,'single');

            app.Slot_Voltage.S1 = zeros(1,app.Data_Package_Length*app.Data_Display_Length,'single');
            app.Slot_Voltage.S2 = zeros(1,app.Data_Package_Length*app.Data_Display_Length,'single');
            app.Slot_Voltage.S3 = zeros(1,app.Data_Package_Length*app.Data_Display_Length,'single');

            % Display
            app.Color_Data = [0.00 0.45 0.74;0.85 0.33 0.10; 0.93 0.69 0.13; 0.49 0.18 0.56; 0.47 0.67 0.19; 0.30 0.75 0.93; 0.64 0.08 0.18];
            app.Color_Index = 0;

            % CMD
            app.CMD_Size = 3;                                               % Kp,Ki,Kd
            app.CMD = zeros(1,app.CMD_Size,'single');

            % System
            app.SysState = uint8(0);
            app.ChargeMode = uint8(0);
        end

        % Drop down opening function: COMMChannelDropDown
        function COMMChannelDropDownOpening(app, event)
            app.COMMChannelDropDown.Items = serialportlist("available");
        end

        % Value changed function: COMMChannelDropDown
        function COMMChannelDropDownValueChanged(app, event)
            if (app.SerialSwitch.Value == "On")
                delete(app.SerialObject);
                try
                    serialconfig(app);
                catch
                    msgbox("Connection Fail","Connection Fail","error");
                    app.COMMSwitch.Value = "Off";
                    app.COMMStateLamp.Color = [1 0 0];                      % Red Light
                    delete(app.SerialObject);
                end
            end
        end

        % Value changed function: COMMSwitch
        function COMMSwitchValueChanged(app, event)
            if (app.COMMSwitch.Value == "On")
                app.COMMStateLamp.Color= [0 1 0];                           % Green Light
                delete(app.SerialObject);
                try
                    serialconfig(app);
                    pause(1);
                    write(app.SerialObject,0x01,"uint8");
                    app.flag_run = 1;
                catch
                    msgbox("Connection Fail","Connection Fail","error");
                    app.COMMSwitch.Value = "Off";
                    app.COMMStateLamp.Color = [1 0 0];                      % Red Light
                    delete(app.SerialObject);
                end
            else
                app.COMMStateLamp.Color = [1 0 0];                          % Red Light
                delete(app.SerialObject);
            end
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            app.CMD(1,1) = app.KpEditField.Value;
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            app.CMD(1,2) = app.KiEditField.Value;

        end

        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            app.CMD(1,3) = app.KdEditField.Value;
        end

        % Value changed function: ChargeModeDropDown
        function ChargeModeDropDownValueChanged(app, event)
            value = app.ChargeModeDropDown.Value;
            if value == "Fast"
                app.ChargeMode = uint8(0);
            else
                app.ChargeMode = uint8(1);
            end
            app.COMM_Type_H = 0x05;
            app.COMM_Type_Change_Flag = 1;
        end

        % Value changed function: SystemStateSwitch
        function SystemStateSwitchValueChanged(app, event)
            value = app.SystemStateSwitch.Value;
            if value == "Off"
                app.SysState = uint8(0);
            else
                app.SysState = uint8(1);
            end
            app.COMM_Type_H = 0x04;
            app.COMM_Type_Change_Flag = 1;
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.COMM_Type_H = 0x03;
            app.COMM_Type_Change_Flag = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.COMMPanel = uipanel(app.UIFigure);
            app.COMMPanel.Title = 'COMM';
            app.GridLayout = uigridlayout(app.COMMPanel);
            app.COMMChannelLabel = uilabel(app.GridLayout);
            app.COMMChannelLabel.Text = 'COMM Channel';
            app.COMMChannelDropDown = uidropdown(app.GridLayout);
            app.COMMChannelDropDown.Items = {};
            app.COMMChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @COMMChannelDropDownOpening, true);
            app.COMMChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @COMMChannelDropDownValueChanged, true);
            app.COMMChannelDropDown.Value = {};
            app.COMMSwitch = uiswitch(app.GridLayout, 'slider');
            app.COMMSwitch.ValueChangedFcn = createCallbackFcn(app, @COMMSwitchValueChanged, true);
            app.COMMStateLampLabel = uilabel(app.GridLayout);
            app.COMMStateLampLabel.Text = 'COMM State';
            app.COMMStateLamp = uilamp(app.GridLayout);
            app.DataPanel = uipanel(app.UIFigure);
            app.DataPanel.Title = 'Data';
            app.GridLayout2 = uigridlayout(app.DataPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.SolarPanelTab = uitab(app.TabGroup);
            app.SolarPanelTab.Title = 'Solar Panel';
            app.GridLayout6 = uigridlayout(app.SolarPanelTab);
            app.InputVoltage_Axes = uiaxes(app.GridLayout6);
            title(app.InputVoltage_Axes, 'Input Voltage')
            xlabel(app.InputVoltage_Axes, 'Time')
            ylabel(app.InputVoltage_Axes, 'Voltage')
            zlabel(app.InputVoltage_Axes, 'Z')
            app.InputCurrent_Axis = uiaxes(app.GridLayout6);
            title(app.InputCurrent_Axis, 'Input Current')
            xlabel(app.InputCurrent_Axis, 'Time')
            ylabel(app.InputCurrent_Axis, 'Voltage')
            zlabel(app.InputCurrent_Axis, 'Z')
            app.InputPower_Axis = uiaxes(app.GridLayout6);
            title(app.InputPower_Axis, 'Input Power')
            xlabel(app.InputPower_Axis, 'Time')
            ylabel(app.InputPower_Axis, 'Voltage')
            zlabel(app.InputPower_Axis, 'Z')
            app.ChargingSlotTab = uitab(app.TabGroup);
            app.ChargingSlotTab.Title = 'Charging Slot';
            app.GridLayout6_2 = uigridlayout(app.ChargingSlotTab);
            app.SlotVoltage_Axis = uiaxes(app.GridLayout6_2);
            title(app.SlotVoltage_Axis, 'Slot Voltage')
            xlabel(app.SlotVoltage_Axis, 'Time')
            ylabel(app.SlotVoltage_Axis, 'Voltage')
            zlabel(app.SlotVoltage_Axis, 'Z')
            app.SlotCurrent_Axis = uiaxes(app.GridLayout6_2);
            title(app.SlotCurrent_Axis, 'Slot Current')
            xlabel(app.SlotCurrent_Axis, 'Time')
            ylabel(app.SlotCurrent_Axis, 'Voltage')
            zlabel(app.SlotCurrent_Axis, 'Z')
            app.SlotPower_Axis = uiaxes(app.GridLayout6_2);
            title(app.SlotPower_Axis, 'Slot Power')
            xlabel(app.SlotPower_Axis, 'Time')
            ylabel(app.SlotPower_Axis, 'Voltage')
            zlabel(app.SlotPower_Axis, 'Z')
            app.BatteryTab = uitab(app.TabGroup);
            app.BatteryTab.Title = 'Battery';
            app.GridLayout4 = uigridlayout(app.BatteryTab);
            app.BatteryLevel_Axis = uiaxes(app.GridLayout4);
            title(app.BatteryLevel_Axis, 'Battery Level')
            xlabel(app.BatteryLevel_Axis, 'Time')
            ylabel(app.BatteryLevel_Axis, 'Battery Level (%)')
            zlabel(app.BatteryLevel_Axis, 'Z')
            app.SystemPanel = uipanel(app.UIFigure);
            app.SystemPanel.Title = 'System';
            app.GridLayout3 = uigridlayout(app.SystemPanel);
            app.KpEditFieldLabel = uilabel(app.GridLayout3);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KiEditFieldLabel = uilabel(app.GridLayout3);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.KdEditFieldLabel = uilabel(app.GridLayout3);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @KdEditFieldValueChanged, true);
            app.SystemStateSwitchLabel = uilabel(app.GridLayout3);
            app.SystemStateSwitchLabel.Text = 'System State';
            app.SystemStateSwitch = uiswitch(app.GridLayout3, 'slider');
            app.SystemStateSwitch.ValueChangedFcn = createCallbackFcn(app, @SystemStateSwitchValueChanged, true);
            app.ChargeModeDropDownLabel = uilabel(app.GridLayout3);
            app.ChargeModeDropDownLabel.Text = 'Charge Mode';
            app.ChargeModeDropDown = uidropdown(app.GridLayout3);
            app.ChargeModeDropDown.Items = {'Fast', 'Balance'};
            app.ChargeModeDropDown.ValueChangedFcn = createCallbackFcn(app, @ChargeModeDropDownValueChanged, true);
            app.ChargeModeDropDown.Value = 'Fast';
            app.UpdateButton = uibutton(app.GridLayout3, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UBC_BMS_Monitor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end